classdef Forstner_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        RuninstructionsMenu            matlab.ui.container.Menu
        LoadImageMenu                  matlab.ui.container.Menu
        SetParametersfortheimageMenu   matlab.ui.container.Menu
        ClicktoloadImage               matlab.ui.container.Menu
        SwitchbetweentabsforresultsMenu  matlab.ui.container.Menu
        FORSTNEROPERATORLabel          matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        LoadTab                        matlab.ui.container.Tab
        DesignedbyWisdomSenaAklamatiLabel  matlab.ui.control.Label
        LoadImageButton                matlab.ui.control.Button
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        eg57or9Label                   matlab.ui.control.Label
        EnterWindowSizeEditField       matlab.ui.control.NumericEditField
        EntertheWindowSizeLabel        matlab.ui.control.Label
        Between0and1Label              matlab.ui.control.Label
        EnterRoundThresholdEditField   matlab.ui.control.NumericEditField
        EnterRoundThresholdLabel       matlab.ui.control.Label
        Between0and1Label_2            matlab.ui.control.Label
        EnterWeightThresholdEditField  matlab.ui.control.NumericEditField
        EnterWeightThresholdEditFieldLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
        ImagetoGrayTab                 matlab.ui.container.Tab
        ConverttoGrayButton            matlab.ui.control.Button
        Gray                           matlab.ui.control.UIAxes
        OriginalImage                  matlab.ui.control.UIAxes
        SobelOperatorTab               matlab.ui.container.Tab
        SobelOperatorButton            matlab.ui.control.Button
        SquaredgradientHor             matlab.ui.control.UIAxes
        SquaredgradientVert            matlab.ui.control.UIAxes
        FilteredgradientHor            matlab.ui.control.UIAxes
        FilteredgradientVert           matlab.ui.control.UIAxes
        GradientXYTab                  matlab.ui.container.Tab
        CalculateGradientButton        matlab.ui.control.Button
        GradientXY                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image1;
        img_gray;
        Grad_vert;
        Grad_horz;
        grad_vert2;
        grad_horz2;
        border_pix;
        row_height;
        col_width;
        W;
        R;
        T;
        gradXY;% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
           
            [filename, pathname] = uigetfile({'*.jpg'},'open file');
            app.FileNameEditField.Value =  [filename pathname];
            
            fullpathname = strcat(pathname,filename);
            app.image1 = imread(fullpathname);
            imshow(app.image1,'parent', app.UIAxes)
        end

        % Button pushed function: ConverttoGrayButton
        function ConverttoGrayButtonPushed(app, event)
[rows, columns, numberOfColorChannels] = size(app.image1);
if numberOfColorChannels > 1
	% Convert from RGB to gray scale.
	app.img_gray = rgb2gray(app.image1);
else
    % It's already a gray scale image.
    app.img_gray = app.image1;
end

imshow(app.image1,'Parent',app.OriginalImage);
imshow(app.img_gray,'Parent',app.Gray);
        end

        % Button pushed function: SobelOperatorButton
        function SobelOperatorButtonPushed(app, event)
app.row_height = size(app.img_gray, 1);
app.col_width = size(app.img_gray, 2);

%Creating zero matrices to hold roundness and weight values
rnd_mat = zeros(app.row_height, app.col_width);
wght_mat = zeros(app.row_height, app.col_width);

app.border_pix = floor(app.W/2);


% Step 2
% ------------------------
% Detect edges using the Sobel operator
app.Grad_vert = edge(app.img_gray,'Sobel', [],'vertical');    % vertical gradient
app.Grad_horz = edge(app.img_gray,'Sobel', [],'horizontal');    % vertical gradient    

imshow(app.Grad_vert,'Parent',app.FilteredgradientVert);

imshow(app.Grad_horz,'Parent',app.FilteredgradientHor);

% Square the gradient values 
app.grad_vert2 = app.Grad_vert.^2;
app.grad_horz2 = app.Grad_horz.^2;

imshow(app.grad_vert2,'Parent',app.SquaredgradientVert);

imshow(app.grad_horz2,'Parent',app.SquaredgradientHor);

        end

        % Button pushed function: CalculateGradientButton
        function CalculateGradientButtonPushed(app, event)
% Multiplication of gradients in x and y direction 
app.gradXY = app.Grad_horz.*app.Grad_vert;

imshow(app.image1, 'Parent', app.GradientXY);


% Solve for the normal matrix for each pixel
for i = 1:app.row_height
    for j = 1:app.col_width
            % Default all the matrix values to zero
            error_ellipse_rnd(i,j) = 0;                     
            error_ellipse_weight(i,j) = 0;
        %box filter
        if (((i >= app.border_pix + 1) && (i <= (app.row_height-app.border_pix))) && (j >= (app.border_pix + 1) && (j <= (app.col_width-app.border_pix))))
            
            % Sum all pixel values within the box filter of the image matrix in vertical direction
            Moving_window_val_vert = app.grad_vert2(i - app.border_pix: i + app.border_pix, j- app.border_pix: j + app.border_pix);  
            sum_total_vert = sum(sum(Moving_window_val_vert));
            
            %  Sum all pixel values within the box filter of the image matrix in horizontal direction
            moving_window_values_horz = app.grad_horz2(i - app.border_pix: i + app.border_pix, j- app.border_pix: j + app.border_pix);  
            sum_total_horz = sum(sum(moving_window_values_horz));
            
            %  Sum all pixel values within the box filter of the image matrix in XY direction
            moving_window_values_XY = app.gradXY(i - app.border_pix: i + app.border_pix, j- app.border_pix: j + app.border_pix);  
            sum_total_XY = sum(sum(moving_window_values_XY));
            
            % Now we formulate the N matrix elements
            N_matrix = [sum_total_vert, sum_total_XY; sum_total_XY, sum_total_horz];
            % determinant =(N11 * N22) - (N21 * N12) as expressed below
            %determinant = (N_matrix(1)*N_matrix(4)) - (N_matrix(2)*N_matrix(3));
            %determine the eigen values of the N Matrix to compute the roundness and weight
            eigen_val = eig(N_matrix);
            lambda1 = eigen_val(1);  
            lambda2 = eigen_val(2);

            
            % Solve for Roundness and Weight matrix
            error_ellipse_rnd(i,j) = (lambda1*lambda2)/(lambda1+lambda2);    
            error_ellipse_weight(i,j) = 1 -((lambda1-lambda2)/(lambda1+lambda2))^2; % Source: C. Fuchs 1992
            %trace is the sum of the diagonal elements of the N matrix
            
            % Run each matrix value against threshold values 
            if (error_ellipse_weight(i,j) > app.T && error_ellipse_rnd(i,j) > app.R)
                hold(app.GradientXY,'all'); %hold is a method that retains the current plot when adding new plots
                set(plot(app.GradientXY,j,i,'y+','MarkerSize',0.5), 'MarkerFaceColor', [0 1 0])
            end
        end
    end
end
        end

        % Value changed function: EnterWindowSizeEditField
        function EnterWindowSizeEditFieldValueChanged(app, event)
           app.W = app.EnterWindowSizeEditField.Value;
            
        end

        % Value changed function: EnterRoundThresholdEditField
        function EnterRoundThresholdEditFieldValueChanged(app, event)
            app.R = app.EnterRoundThresholdEditField.Value;
            
        end

        % Value changed function: EnterWeightThresholdEditField
        function EnterWeightThresholdEditFieldValueChanged(app, event)
            app.T = app.EnterWeightThresholdEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.RuninstructionsMenu = uimenu(app.Menu);
            app.RuninstructionsMenu.Text = 'Run instructions';
            app.LoadImageMenu = uimenu(app.RuninstructionsMenu);
            app.LoadImageMenu.Separator = 'on';
            app.LoadImageMenu.Text = '* Load Image';
            app.SetParametersfortheimageMenu = uimenu(app.RuninstructionsMenu);
            app.SetParametersfortheimageMenu.Text = '* Set Parameters for the image';
            app.ClicktoloadImage = uimenu(app.RuninstructionsMenu);
            app.ClicktoloadImage.Text = '* Click Load Image';
            app.SwitchbetweentabsforresultsMenu = uimenu(app.RuninstructionsMenu);
            app.SwitchbetweentabsforresultsMenu.Text = '* Switch between tabs for results';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.UIAxes = uiaxes(app.LoadTab);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'Columns')
            ylabel(app.UIAxes, 'Rows')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.LoadTab);
            app.Image.ImageSource = 'HFT.jpg';
            app.EnterWeightThresholdEditFieldLabel = uilabel(app.LoadTab);
            app.EnterWeightThresholdEditFieldLabel.Text = 'Enter Weight Threshold ';
            app.EnterWeightThresholdEditField = uieditfield(app.LoadTab, 'numeric');
            app.EnterWeightThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @EnterWeightThresholdEditFieldValueChanged, true);
            app.Between0and1Label_2 = uilabel(app.LoadTab);
            app.Between0and1Label_2.Text = 'Between 0 and 1';
            app.EnterRoundThresholdLabel = uilabel(app.LoadTab);
            app.EnterRoundThresholdLabel.Text = 'Enter Round Threshold ';
            app.EnterRoundThresholdEditField = uieditfield(app.LoadTab, 'numeric');
            app.EnterRoundThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @EnterRoundThresholdEditFieldValueChanged, true);
            app.Between0and1Label = uilabel(app.LoadTab);
            app.Between0and1Label.Text = 'Between 0 and 1';
            app.EntertheWindowSizeLabel = uilabel(app.LoadTab);
            app.EntertheWindowSizeLabel.Text = 'Enter Window Size ';
            app.EnterWindowSizeEditField = uieditfield(app.LoadTab, 'numeric');
            app.EnterWindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @EnterWindowSizeEditFieldValueChanged, true);
            app.eg57or9Label = uilabel(app.LoadTab);
            app.eg57or9Label.Text = '(e.g. 5, 7 or 9)';
            app.FileNameEditFieldLabel = uilabel(app.LoadTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.LoadTab, 'text');
            app.LoadImageButton = uibutton(app.LoadTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DesignedbyWisdomSenaAklamatiLabel = uilabel(app.LoadTab);
            app.DesignedbyWisdomSenaAklamatiLabel.Interpreter = 'latex';
            app.DesignedbyWisdomSenaAklamatiLabel.Text = 'Designed by: Wisdom Sena Aklamati';
            app.ImagetoGrayTab = uitab(app.TabGroup);
            app.ImagetoGrayTab.Title = 'Image to Gray';
            app.OriginalImage = uiaxes(app.ImagetoGrayTab);
            title(app.OriginalImage, 'Original Image')
            xlabel(app.OriginalImage, 'Column')
            ylabel(app.OriginalImage, 'Rows')
            zlabel(app.OriginalImage, 'Z')
            app.Gray = uiaxes(app.ImagetoGrayTab);
            title(app.Gray, 'Gray Image')
            xlabel(app.Gray, 'Column')
            ylabel(app.Gray, 'Rows')
            zlabel(app.Gray, 'Z')
            app.ConverttoGrayButton = uibutton(app.ImagetoGrayTab, 'push');
            app.ConverttoGrayButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoGrayButtonPushed, true);
            app.ConverttoGrayButton.Text = 'Convert to Gray';
            app.SobelOperatorTab = uitab(app.TabGroup);
            app.SobelOperatorTab.Title = 'Sobel Operator';
            app.FilteredgradientVert = uiaxes(app.SobelOperatorTab);
            title(app.FilteredgradientVert, 'Filtered Gradient Vertical ')
            xlabel(app.FilteredgradientVert, 'Columns')
            ylabel(app.FilteredgradientVert, 'Rows')
            zlabel(app.FilteredgradientVert, 'Z')
            app.FilteredgradientHor = uiaxes(app.SobelOperatorTab);
            title(app.FilteredgradientHor, 'Filtered Gradient Horizontal')
            xlabel(app.FilteredgradientHor, 'Columns')
            ylabel(app.FilteredgradientHor, 'Rows')
            zlabel(app.FilteredgradientHor, 'Z')
            app.SquaredgradientVert = uiaxes(app.SobelOperatorTab);
            title(app.SquaredgradientVert, 'Squared Gradient Vertical ')
            xlabel(app.SquaredgradientVert, 'Columns')
            ylabel(app.SquaredgradientVert, 'Rows')
            zlabel(app.SquaredgradientVert, 'Z')
            app.SquaredgradientHor = uiaxes(app.SobelOperatorTab);
            title(app.SquaredgradientHor, 'Squared Gradient Horizontal')
            xlabel(app.SquaredgradientHor, 'Columns')
            ylabel(app.SquaredgradientHor, 'Rows')
            zlabel(app.SquaredgradientHor, 'Z')
            app.SobelOperatorButton = uibutton(app.SobelOperatorTab, 'push');
            app.SobelOperatorButton.ButtonPushedFcn = createCallbackFcn(app, @SobelOperatorButtonPushed, true);
            app.SobelOperatorButton.Text = 'Sobel Operator';
            app.GradientXYTab = uitab(app.TabGroup);
            app.GradientXYTab.Title = 'Gradient XY';
            app.GradientXY = uiaxes(app.GradientXYTab);
            title(app.GradientXY, 'Selected points')
            xlabel(app.GradientXY, 'Columns')
            ylabel(app.GradientXY, 'Rows')
            zlabel(app.GradientXY, 'Z')
            app.CalculateGradientButton = uibutton(app.GradientXYTab, 'push');
            app.CalculateGradientButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateGradientButtonPushed, true);
            app.CalculateGradientButton.Text = 'Calculate Gradient';
            app.FORSTNEROPERATORLabel = uilabel(app.UIFigure);
            app.FORSTNEROPERATORLabel.Text = 'FORSTNER OPERATOR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Forstner_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end